classdef MyRobot_Studio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenGripperButton               matlab.ui.control.Button
        SmoothMotionButton              matlab.ui.control.Button
        MoveCartesianButton             matlab.ui.control.Button
        ZmEditField                     matlab.ui.control.NumericEditField
        ZmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        OutputButton                    matlab.ui.control.Button
        PlayConfigurationHistoryButton  matlab.ui.control.Button
        DelLastConfigurationButton      matlab.ui.control.Button
        RecordConfiguration0Button      matlab.ui.control.Button
        ZmmLabel                        matlab.ui.control.Label
        YmmLabel                        matlab.ui.control.Label
        XmmLabel                        matlab.ui.control.Label
        EndeffectorLabel                matlab.ui.control.Label
        ShowConfigurationButton         matlab.ui.control.StateButton
        torque100Label                  matlab.ui.control.Label
        speed10Label                    matlab.ui.control.Label
        j4_angleLabel                   matlab.ui.control.Label
        j3_angleLabel                   matlab.ui.control.Label
        j2_angleLabel                   matlab.ui.control.Label
        j1_angleLabel                   matlab.ui.control.Label
        UprightButton                   matlab.ui.control.StateButton
        XmEditField                     matlab.ui.control.NumericEditField
        XmEditFieldLabel                matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        JointTorqueEditField            matlab.ui.control.NumericEditField
        JointTorqueLabel                matlab.ui.control.Label
        JointSpeedEditField             matlab.ui.control.NumericEditField
        JointSpeedLabel                 matlab.ui.control.Label
        Movej21800EditField             matlab.ui.control.NumericEditField
        Movej21800EditFieldLabel        matlab.ui.control.Label
        Movej4100100EditField           matlab.ui.control.NumericEditField
        Movej4100100EditFieldLabel      matlab.ui.control.Label
        Movej3100100EditField           matlab.ui.control.NumericEditField
        Movej3100100EditFieldLabel      matlab.ui.control.Label
        Movej1130130EditField           matlab.ui.control.NumericEditField
        Movej1130130EditFieldLabel      matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        ConnectRobotButton              matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        rob = 0; % Description
        input_joint_angle = [0 0 0 0];
        input_ee_position
    end
    
    methods (Access = private)
        
        function update_gui(app)
            joint_angles = app.rob.read_joint_angles();
            app.j1_angleLabel.Text = "j1 [°]: " + {num2str(joint_angles(1))};
            app.j2_angleLabel.Text = "j2 [°]: " + {num2str(joint_angles(2))};
            app.j3_angleLabel.Text = "j3 [°]: " + {num2str(joint_angles(3))};
            app.j4_angleLabel.Text = "j4 [°]: " + {num2str(joint_angles(4))};
            
            if ~isempty(app.rob.movement_history)
                length_history = size(app.rob.movement_history);
                nm_configurations = length_history(1);
                app.RecordConfiguration0Button.Text = "Record Configuration: " + num2str(nm_configurations);
            elseif isempty(app.rob.movement_history)
                app.RecordConfiguration0Button.Text = "Record Configuration: 0";
            end
            ee_pos = app.rob.read_ee_position();
            app.XmmLabel.Text = "X [mm]: " + sprintf('%0.1f', ee_pos(1)*1000);
            app.YmmLabel.Text = "Y [mm]: " + sprintf('%0.1f', ee_pos(2)*1000);
            app.ZmmLabel.Text = "Z [mm]: " + sprintf('%0.1f', ee_pos(3)*1000);
            app.XmEditField.Value = round(app.rob.joint_pos(1,4) * 1000,2);
            app.YmEditField.Value = round(app.rob.joint_pos(2,4) * 1000,2);
            app.ZmEditField.Value = round(app.rob.joint_pos(3,4) * 1000,2);

            app.torque100Label.Text = "torque [%]: " + num2str(app.rob.motor_torque(1)*100);
            app.speed10Label.Text = "speed [%]: " + num2str(app.rob.motor_speed(1)*100);
            app.JointSpeedEditField.Value = app.rob.motor_speed(1)*100;
            app.Movej1130130EditField.Value = app.rob.joint_angles(1);
            app.Movej21800EditField.Value = app.rob.joint_angles(2);
            app.Movej3100100EditField.Value = app.rob.joint_angles(3);
            app.Movej4100100EditField.Value = app.rob.joint_angles(4);
            app.JointTorqueEditField.Value = app.rob.motor_torque(1)*100;
            if app.rob.use_smooth_speed_flag==0
                app.SmoothMotionButton.Text = "Smooth Motion";
            else
                app.SmoothMotionButton.Text = "Stop Smooth Motion";
            end
            
            if app.rob.gripper_open_flag
                app.OpenGripperButton.Text = "Close Gripper";
            else
                app.OpenGripperButton.Text = "Open Gripper";
            end
            

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectRobotButton
        function ConnectRobotButtonValueChanged(app, event)
            value = app.ConnectRobotButton.Value;
            if value
                app.rob = MyRobot();
                
                if app.rob.init_status
                    app.StatusLamp.Color = 'green';
                    app.ConnectRobotButton.Text = "Disconnect Robot";
                    app.update_gui();
                    app.RecordConfiguration0Button.Text = "Record Configuration: 0";

                end
            elseif ~value
                app.rob.disable_motors();
                app.StatusLamp.Color = 'red';
                app.ConnectRobotButton.Text = "Connect Robot";
                app.RecordConfiguration0Button.Text = "Record Configuration: 0";

            end
        end

        % Value changed function: JointSpeedEditField
        function JointSpeedEditFieldValueChanged(app, event)
            value = app.JointSpeedEditField.Value*0.01;
            app.rob.set_speed([value,value,value,value],true);
            app.update_gui();
        end

        % Value changed function: JointTorqueEditField
        function JointTorqueEditFieldValueChanged(app, event)
            value = app.JointTorqueEditField.Value*0.01;
            app.rob.set_torque_limit([value,value,value,value]);
            app.update_gui();
        end

        % Value changed function: Movej1130130EditField
        function Movej1130130EditFieldValueChanged(app, event)
            app.input_joint_angle(1) = app.Movej1130130EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: Movej21800EditField
        function Movej21800EditFieldValueChanged(app, event)
            app.input_joint_angle(2) = app.Movej21800EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: Movej3100100EditField
        function Movej3100100EditFieldValueChanged(app, event)
            app.input_joint_angle(3) = app.Movej3100100EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: Movej4100100EditField
        function Movej4100100EditFieldValueChanged(app, event)
            app.input_joint_angle(4) = app.Movej4100100EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: UprightButton
        function UprightButtonValueChanged(app, event)
            value = app.UprightButton.Value;
            app.input_joint_angle = [0,-90,0,0];
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
            app.UprightButton.Value = 0;
        end

        % Value changed function: ShowConfigurationButton
        function ShowConfigurationButtonValueChanged(app, event)
            value = app.ShowConfigurationButton.Value;
            if value
                app.rob.draw_robot();
                app.ShowConfigurationButton.Text = "Hide Configuration";
            elseif ~value
                close all;
                app.rob.draw_robot_flag = 0;
                app.ShowConfigurationButton.Text = "Show Configuration";
            end
        end

        % Button pushed function: RecordConfiguration0Button
        function RecordConfiguration0ButtonPushed(app, event)
            app.rob.record_configuration();
            app.update_gui();
        end

        % Button pushed function: DelLastConfigurationButton
        function DelLastConfigurationButtonPushed(app, event)
            app.rob.delete_last_recorded_configuration();
            app.update_gui();
        end

        % Button pushed function: PlayConfigurationHistoryButton
        function PlayConfigurationHistoryButtonPushed(app, event)
            app.PlayConfigurationHistoryButton.Text = "Playing...";
            app.rob.play_configuration_history();
            app.PlayConfigurationHistoryButton.Text = "Play Configuration History";
            app.update_gui();
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            app.update_gui();
        end

        % Button pushed function: MoveCartesianButton
        function MoveCartesianButtonPushed(app, event)
            x = app.XmEditField.Value;
            y = app.YmEditField.Value;
            z = app.ZmEditField.Value;
            app.rob.move_c(x,y,z);
            app.update_gui();
        end

        % Button pushed function: SmoothMotionButton
        function SmoothMotionButtonPushed(app, event)
            if app.rob.use_smooth_speed_flag == 1
                app.rob.use_smooth_speed_flag = 0;
            else
                app.rob.use_smooth_speed_flag = 1;
            end
            app.update_gui();
        end

        % Button pushed function: OpenGripperButton
        function OpenGripperButtonPushed(app, event)
            app.rob.actuate_gripper();
            app.update_gui();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectRobotButton = uibutton(app.UIFigure, 'state');
            app.ConnectRobotButton.ValueChangedFcn = createCallbackFcn(app, @ConnectRobotButtonValueChanged, true);
            app.ConnectRobotButton.Text = 'Connect Robot';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.Movej1130130EditFieldLabel = uilabel(app.UIFigure);
            app.Movej1130130EditFieldLabel.Text = 'Move j1 [-130°,130°]';
            app.Movej1130130EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej1130130EditField.Limits = [-130 130];
            app.Movej1130130EditField.ValueChangedFcn = createCallbackFcn(app, @Movej1130130EditFieldValueChanged, true);
            app.Movej3100100EditFieldLabel = uilabel(app.UIFigure);
            app.Movej3100100EditFieldLabel.Text = 'Move j3 [-100°,100°]';
            app.Movej3100100EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej3100100EditField.Limits = [-100 100];
            app.Movej3100100EditField.ValueChangedFcn = createCallbackFcn(app, @Movej3100100EditFieldValueChanged, true);
            app.Movej4100100EditFieldLabel = uilabel(app.UIFigure);
            app.Movej4100100EditFieldLabel.Text = 'Move j4 [-100°,100°]';
            app.Movej4100100EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej4100100EditField.Limits = [-100 100];
            app.Movej4100100EditField.ValueChangedFcn = createCallbackFcn(app, @Movej4100100EditFieldValueChanged, true);
            app.Movej21800EditFieldLabel = uilabel(app.UIFigure);
            app.Movej21800EditFieldLabel.Text = 'Move j2 [-180°,0°]';
            app.Movej21800EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej21800EditField.Limits = [-180 0];
            app.Movej21800EditField.ValueChangedFcn = createCallbackFcn(app, @Movej21800EditFieldValueChanged, true);
            app.JointSpeedLabel = uilabel(app.UIFigure);
            app.JointSpeedLabel.Text = 'Joint Speed [%]';
            app.JointSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointSpeedEditField.Limits = [0 100];
            app.JointSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @JointSpeedEditFieldValueChanged, true);
            app.JointSpeedEditField.Value = 10;
            app.JointTorqueLabel = uilabel(app.UIFigure);
            app.JointTorqueLabel.Text = 'Joint Torque [%]';
            app.JointTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointTorqueEditField.Limits = [0 100];
            app.JointTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @JointTorqueEditFieldValueChanged, true);
            app.JointTorqueEditField.Value = 100;
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.XmEditFieldLabel = uilabel(app.UIFigure);
            app.XmEditFieldLabel.Text = 'X [m]';
            app.XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmEditField.Value = 1;
            app.UprightButton = uibutton(app.UIFigure, 'state');
            app.UprightButton.ValueChangedFcn = createCallbackFcn(app, @UprightButtonValueChanged, true);
            app.UprightButton.Text = 'Upright';
            app.j1_angleLabel = uilabel(app.UIFigure);
            app.j1_angleLabel.Text = 'j1_angle';
            app.j2_angleLabel = uilabel(app.UIFigure);
            app.j2_angleLabel.Text = 'j2_angle';
            app.j3_angleLabel = uilabel(app.UIFigure);
            app.j3_angleLabel.Text = 'j3_angle';
            app.j4_angleLabel = uilabel(app.UIFigure);
            app.j4_angleLabel.Text = 'j4_angle';
            app.speed10Label = uilabel(app.UIFigure);
            app.speed10Label.Text = 'speed [%]: 10';
            app.torque100Label = uilabel(app.UIFigure);
            app.torque100Label.Text = 'torque [%]: 100';
            app.ShowConfigurationButton = uibutton(app.UIFigure, 'state');
            app.ShowConfigurationButton.ValueChangedFcn = createCallbackFcn(app, @ShowConfigurationButtonValueChanged, true);
            app.ShowConfigurationButton.Text = 'Show Configuration';
            app.EndeffectorLabel = uilabel(app.UIFigure);
            app.EndeffectorLabel.Text = 'End-effector';
            app.XmmLabel = uilabel(app.UIFigure);
            app.XmmLabel.Text = 'X [mm]:';
            app.YmmLabel = uilabel(app.UIFigure);
            app.YmmLabel.Text = 'Y [mm]:';
            app.ZmmLabel = uilabel(app.UIFigure);
            app.ZmmLabel.Text = 'Z [mm]:';
            app.RecordConfiguration0Button = uibutton(app.UIFigure, 'push');
            app.RecordConfiguration0Button.ButtonPushedFcn = createCallbackFcn(app, @RecordConfiguration0ButtonPushed, true);
            app.RecordConfiguration0Button.Text = 'Record Configuration: 0';
            app.DelLastConfigurationButton = uibutton(app.UIFigure, 'push');
            app.DelLastConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @DelLastConfigurationButtonPushed, true);
            app.DelLastConfigurationButton.Text = 'Del Last Configuration';
            app.PlayConfigurationHistoryButton = uibutton(app.UIFigure, 'push');
            app.PlayConfigurationHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlayConfigurationHistoryButtonPushed, true);
            app.PlayConfigurationHistoryButton.Text = 'Play Configuration History';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            app.YmEditFieldLabel = uilabel(app.UIFigure);
            app.YmEditFieldLabel.Text = 'Y [m]';
            app.YmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmEditFieldLabel.Text = 'Z [m]';
            app.ZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MoveCartesianButton = uibutton(app.UIFigure, 'push');
            app.MoveCartesianButton.ButtonPushedFcn = createCallbackFcn(app, @MoveCartesianButtonPushed, true);
            app.MoveCartesianButton.Text = 'Move Cartesian';
            app.SmoothMotionButton = uibutton(app.UIFigure, 'push');
            app.SmoothMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothMotionButtonPushed, true);
            app.SmoothMotionButton.Text = 'Smooth Motion';
            app.OpenGripperButton = uibutton(app.UIFigure, 'push');
            app.OpenGripperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGripperButtonPushed, true);
            app.OpenGripperButton.Text = 'Open Gripper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyRobot_Studio

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end